classdef StimSoftware < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ResponsevsPhaseparametersPanel  matlab.ui.container.Panel
        lbl_RvPTotalLength              matlab.ui.control.Label
        txt_RvPJitter                   matlab.ui.control.EditField
        jittersEditFieldLabel           matlab.ui.control.Label
        txt_RvPStimSeparation           matlab.ui.control.EditField
        stimseparationsLabel            matlab.ui.control.Label
        txt_RvPStimTrials               matlab.ui.control.EditField
        TrialsperstimLabel              matlab.ui.control.Label
        WavFileInformationfreevocalizingbatsPanel  matlab.ui.container.Panel
        but_ChangeSaveRecWavPath        matlab.ui.control.Button
        txt_RecWavIndex                 matlab.ui.control.EditField
        IndexLabel_2                    matlab.ui.control.Label
        txt_RecWavBaseName              matlab.ui.control.EditField
        BaseNameLabel_2                 matlab.ui.control.Label
        txt_RecWavFilePath              matlab.ui.control.EditField
        SavingPathEditFieldLabel_2      matlab.ui.control.Label
        PlayRecParamsPanel              matlab.ui.container.Panel
        txt_FreeVocRecDuration          matlab.ui.control.EditField
        RecDurationminLabel             matlab.ui.control.Label
        txt_SyncToneDuration            matlab.ui.control.EditField
        SyncToneDursLabel               matlab.ui.control.Label
        txt_SyncToneFreq                matlab.ui.control.EditField
        SyncToneFreqHzLabel             matlab.ui.control.Label
        check_FreelyVocProtocol         matlab.ui.control.CheckBox
        but_PlaySounds                  matlab.ui.control.Button
        txt_AudioDevice                 matlab.ui.control.EditField
        DeviceEditFieldLabel            matlab.ui.control.Label
        txt_SamplesPerFrame             matlab.ui.control.EditField
        SampPerFrameLabel               matlab.ui.control.Label
        txt_OutputChs                   matlab.ui.control.EditField
        OutputChEditFieldLabel          matlab.ui.control.Label
        txt_InputChs                    matlab.ui.control.EditField
        InputChEditFieldLabel           matlab.ui.control.Label
        ProtocolSavingFileInformationPanel  matlab.ui.container.Panel
        but_SaveStimMetadata            matlab.ui.control.Button
        but_ChangeSavePath              matlab.ui.control.Button
        txt_ProtFileIndex               matlab.ui.control.EditField
        IndexLabel                      matlab.ui.control.Label
        txt_ProtFileBaseName            matlab.ui.control.EditField
        BaseNameLabel                   matlab.ui.control.Label
        txt_ProtFilePath                matlab.ui.control.EditField
        SavingPathEditFieldLabel        matlab.ui.control.Label
        GenerateFullStimulationButton   matlab.ui.control.Button
        ParadigmdesignPanel             matlab.ui.container.Panel
        StimulationTypeDropDown         matlab.ui.control.DropDown
        StimulationTypeDropDownLabel    matlab.ui.control.Label
        but_AddStimType                 matlab.ui.control.Button
        txt_StimType2Add                matlab.ui.control.EditField
        but_RemoveStimType              matlab.ui.control.Button
        lbl_Status                      matlab.ui.control.Label
        Panel                           matlab.ui.container.Panel
        OriginalllyEstablishedLevdBSPLEditField  matlab.ui.control.NumericEditField
        OriginalllyEstablishedLevdBSPLEditFieldLabel  matlab.ui.control.Label
        but_LoadCalibCurve              matlab.ui.control.Button
        txt_CalibCurvePath              matlab.ui.control.EditField
        calibpathEditFieldLabel         matlab.ui.control.Label
        axes_CalibCurve                 matlab.ui.control.UIAxes
        SoundbatterywavfilesPanel       matlab.ui.container.Panel
        lbl_NumStimLoaded               matlab.ui.control.Label
        but_LoadStimFolder              matlab.ui.control.Button
        txt_SoundBatteryPath            matlab.ui.control.EditField
        pathEditFieldLabel              matlab.ui.control.Label
        FrequencyTuninggeneratesPanel   matlab.ui.container.Panel
        txt_FTDuration                  matlab.ui.control.EditField
        durationsLabel                  matlab.ui.control.Label
        but_Generate                    matlab.ui.control.Button
        txt_dBStep                      matlab.ui.control.EditField
        dBStepEditFieldLabel            matlab.ui.control.Label
        txt_dBSPLEnd                    matlab.ui.control.EditField
        dBSPLEndEditFieldLabel          matlab.ui.control.Label
        txt_dBSPLStart                  matlab.ui.control.EditField
        dBSPLStartEditFieldLabel        matlab.ui.control.Label
        LinearfreqgenerationLabel       matlab.ui.control.Label
        txt_FreqStep                    matlab.ui.control.EditField
        FreqStepEditFieldLabel          matlab.ui.control.Label
        txt_FreqEnd                     matlab.ui.control.EditField
        FreqEndEditFieldLabel           matlab.ui.control.Label
        txt_FreqStart                   matlab.ui.control.EditField
        FreqStartkHzEditFieldLabel      matlab.ui.control.Label
        StimulationParametersPanel      matlab.ui.container.Panel
        StimSepsLabel                   matlab.ui.control.Label
        txt_eStimNoAudioSeparation      matlab.ui.control.EditField
        NTrialsEditFieldLabel           matlab.ui.control.Label
        txt_eStimNtrialsNoAudio         matlab.ui.control.EditField
        EAstimOffsetLabel               matlab.ui.control.Label
        txt_EAStimOffset                matlab.ui.control.EditField
        TimeposteAStimsLabel            matlab.ui.control.Label
        txt_TimePostEAStim              matlab.ui.control.EditField
        postCyclesEditFieldLabel        matlab.ui.control.Label
        txt_PostCyclesEStim             matlab.ui.control.EditField
        phaseBinsEditFieldLabel         matlab.ui.control.Label
        txt_phaseBinsEStim              matlab.ui.control.EditField
        N_estimLabel                    matlab.ui.control.Label
        txt_NeStim                      matlab.ui.control.EditField
        estim_rateHzLabel               matlab.ui.control.Label
        txt_eStimRate                   matlab.ui.control.EditField
        TTLdursLabel                    matlab.ui.control.Label
        txt_TTLDur                      matlab.ui.control.NumericEditField
        fsHzEditFieldLabel              matlab.ui.control.Label
        txt_Fs                          matlab.ui.control.NumericEditField
        NtrialsLabel                    matlab.ui.control.Label
        txt_Ntrials                     matlab.ui.control.EditField
        rampdursEditFieldLabel          matlab.ui.control.Label
        txt_RampDuration                matlab.ui.control.EditField
        interstimintsEditFieldLabel     matlab.ui.control.Label
        txt_IStimI                      matlab.ui.control.EditField
        posttimesEditFieldLabel         matlab.ui.control.Label
        txt_PostTime                    matlab.ui.control.EditField
        pretimesEditFieldLabel          matlab.ui.control.Label
        txt_PreTime                     matlab.ui.control.EditField
        ElectricalstimulationthingsLabel  matlab.ui.control.Label
        check_NoAcousticStim            matlab.ui.control.CheckBox
    end

    
    properties (Access = private)
        
        % datapaths
        datapath_calibcurve = ''; % path to the calibration curve.. full name (file and all)
        datapath_soundbattery = ''; % path to the data where sounds to be presented from file are
        datapath_protmetadata = ''; % path to where protocol metadata files are stored
        datapath_recwavdata = ''; % path to where wav files will be stored...
        
        % calibration curve
        cal_f = [];
        cal_curve = [];
        
        % stimulation properties
        ramp_duration = 0;
        fs = 0;
        
        % wavfiles
        wavfiles = []; % wav files in sound battery directory
        wavstims = {};
        
        % freq_tuning stimuli
        ft_freqs = [];
        ft_levels = [];
        ft_stims = {}; % freq, levels (2D cell array)
        
        % general stimulation properties...
        Ntrials = 0;
        ISI = -1;
        pre_time = 0;
        post_time = 0;
        TTL_duration = 0;
        
        running_protocol = ''; % this is the identifier (char) of the protocol
        stim_protocol = []; % large stimulation protocol stimulus (concatenating all
                                % random stimuli to be presented.. whatever
                                % they are -> wav files or freq tuning)...
                                % Including a channel for the trigger...
                                
        protocol_table = [];
        
        
        % electrical stimulation properties
        estim_rate = 0 % the rate of a single block of eletrical stimulation (Hz)
        estim_nperblock = 0; % how many stims there are in a single block
        estim_acousticstim = 0; % is there acoustic stim after the electric one?
        estim_Nphasebins = 0; % the number of phase bins to check post estim
        estim_Npostcycles = 0; % how many cycles after estim to present stimulation
        estim_acousticstim_offset = 0 % offset in time between estim and acoustic stim
        estim_timePostEAStim % the time to wait after even considering doing another electrical stim counted from the last electrical pulse onset
        estim_stimphase_map = []; % a map of which stimulus was presented with on which trial...
        estim_noacousticstim = 1; % check whether there should be no acoustic stimulus after electric pulses
        estim_noacoustic_ntrials = 0; % number of trials with the above
        estim_noacoustic_stimsep = -1; % separation between electrical stimulation...
        
        
        % properties for the response vs. phase protocol
        rvp_jitter = 0; % the jitter used for the stimuli
        rvp_trialsperstim = 0; % the number of trials per individual stimuli
        rvp_stimseparation = 0; % the trial ap
        rvp_stimtrial_map = []; % the map of which stimulus was presented with which trigger

    end
    
    methods (Access = private)
        
        function ret = ramp_stimulus_onoff( app, x )
            
            if ( app.ramp_duration <= 0 ) 
                ret = x; return; end;
            
            % make the ramp...
            samples_rampon = ceil( app.ramp_duration .* app.fs );
            ramp_on = linspace( 0, 1, samples_rampon );
            ramp = [ ramp_on, ones( 1, numel(x) - 2 .* numel(ramp_on) ), fliplr( ramp_on ) ];
            
            ret = x .* ramp;
        end
        
        function ret = build_protocol_table( app, runnprotocol, playorder, triggers, stimPhasesIdList, trigpos_estimNoAudio, rvp_stimtrial_map )
            
            if ( strcmp( runnprotocol, 'FreqTuning' ) )
                
                % a fixed thing for the frequency tuning protocol
                [freqId, levelId] = ind2sub( size(app.ft_stims), playorder );
                ret = table( playorder', triggers', freqId', levelId', app.ft_freqs(freqId)', app.ft_levels(levelId)', ...
                             'VariableNames', { 'PlayOrder', 'TriggerSamples', 'freqId', 'levelId', 'RealFreq', 'RealLevel' } );
            elseif ( strcmp( runnprotocol, 'ElectricalStim' ) )
                
                % this is funny. this is the protocol for the electrical
                % stmiulation, I need to save the map corresponding to each
                % "trial". A trial is the sequence of electric pulses, plus
                % the acoustic stimulus. It may be that there isn't any
                % acoustic stimulus. We'll deal with that later

                if ( app.estim_noacousticstim )
                    ret = table( trigpos_estimNoAudio', 'VariableNames', {'FirstTriggerPositions'} );
                else
                    ret = table( stimPhasesIdList( 1, playorder )', stimPhasesIdList( 2, playorder )', {app.wavfiles( stimPhasesIdList(1,playorder) ).name}', ...
                               'VariableNames', { 'StimulusId', 'PhaseId', 'WavfileNames' } );
                end
                
            elseif ( strcmp( runnprotocol, 'ResponseVsPhase' ) )
                
                % this saves the protocol for the response vs. phase
                % experiments. We just make a stimple table that maps the
                % stimulus trials across time (for each trigger position).
                
                ret = table( triggers', rvp_stimtrial_map', {app.wavfiles(rvp_stimtrial_map).name}', ...
                                'VariableNames', { 'TriggerSamples', 'StimTrialMap', 'WavfileNames' } );
            else 
                
                % here, for the protocols that are not frequency tuning,
                % whatever their identifier is... This happens when wav
                % file sound battery is purely done..
                ret = table( playorder', triggers', {app.wavfiles(playorder).name}', ...
                             'VariableNames', { 'PlayOrder', 'TriggerSamples', 'WavfileNames' } );
            end
        end
        
        function save_prot_metadata_file( app )
            
            % get the full path, filename and all...
            basename = app.txt_ProtFileBaseName.Value;
            idx_suffix = app.txt_ProtFileIndex.Value;
            file2save = [ basename, '_', idx_suffix ];
            fullname = [ app.txt_ProtFilePath.Value, '\', file2save ];
            
            % some things about acoustic stimulation
            protT = app.protocol_table;
            pretime = app.pre_time;
            posttime = app.post_time;
            ISI = app.ISI;
            Ntrials = app.Ntrials;
            Fs_stim = app.fs;
            TTL_duration = app.TTL_duration;
            isFreeVoc = double( app.check_FreelyVocProtocol.Value );
            curr_protocol = app.StimulationTypeDropDown.Value;
            
            % things specific to electrical stimulation...
            estim_rate = app.estim_rate; % the rate of a single block of eletrical stimulation (Hz)
            estim_nperblock = app.estim_nperblock; % how many stims there are in a single block
            estim_acousticstim = app.estim_acousticstim; % is there acoustic stim after the electric one?
            estim_Nphasebins = app.estim_Nphasebins; % the number of phase bins to check post estim
            estim_Npostcycles = app.estim_Npostcycles; % how many cycles after estim to present stimulation
            estim_acousticstim_offset = app.estim_acousticstim_offset; % offset in time between estim and acoustic stim
            estim_timePostEAStim = app.estim_timePostEAStim; % the time to wait after even considering doing another electrical stim counted from the last electrical pulse onset
            estim_stimphase_map = app.estim_stimphase_map; % a map of which stimulus was presented with on which trial...
            
            % things that have to do with response vs. phase
            rvp_jitter = app.rvp_jitter; % the jitter used for the stimuli
            rvp_trialsperstim = app.rvp_trialsperstim; % the number of trials per individual stimuli
            rvp_stimseparation = app.rvp_stimseparation; % the trial ap
            rvp_stimtrial_map = app.rvp_stimtrial_map; % the map of which stimulus was presented with which trigger
            
            % do the saving
            save( [ fullname, '.mat' ], 'protT', 'pretime', 'posttime', 'ISI', 'Ntrials', ...
                'Fs_stim', 'TTL_duration', 'isFreeVoc', 'curr_protocol', ... % below come the stuff about electrical stimulation
                'estim_rate', 'estim_nperblock', 'estim_acousticstim', 'estim_Nphasebins', 'estim_Npostcycles', 'estim_acousticstim_offset', ...
                'estim_timePostEAStim', 'estim_stimphase_map', ... % below come stuff that have to do with Response vs. Phase
                'rvp_jitter', 'rvp_trialsperstim', 'rvp_stimseparation', 'rvp_stimtrial_map' );
            
            
            % --------------------------------------------------- %
            % save the above also as an HDF5 file, so that it doesn't
            % require matlab to be parsed... Think about data-sharing and
            % whatnot...
            
            % let's make first the table into a struct (can be saved into a
            % hdf5)...
            % I'll traverse the protocol table, and save stuff inside of it as a
            % data field in the H5 file. Iteratively. Cannot think of
            % anything else at the moment, and it shoudl be fine...
            
            if ( exist( [fullname, '.h5'] ) ) 
                % overwrite and create a new H5... mind it!!!!
                delete( [fullname, '.h5'] );
            end
            
            var_names = protT.Properties.VariableNames;
            for k = 1 : numel( var_names )
                
                varnow = char( var_names{k} );
                datanow = protT.(varnow);
                
                % check if it's a numeric field. If it's not a numeric
                % field, then we'll treat it as a string array...
                if ( isnumeric( datanow ) )
                    h5create( [fullname, '.h5'], ['/', varnow], size(datanow) );
                    h5write( [fullname, '.h5'], ['/',varnow], datanow );
                else
                    h5create( [fullname, '.h5'], ['/', varnow], size(datanow), 'Datatype', 'string' );
                    strdata = string(datanow);
                    h5write( [fullname, '.h5'], ['/', varnow], strdata );
                end
            end
            % and now, let's save
            otherfields = { '/pretime', '/posttime', '/ISI', '/Ntrials', ...
                '/Fs_stim', '/TTL_duration', '/isFreeVoc', ... % below come other fields that have to do with electrical stim
                '/estim_rate', '/estim_nperblock', '/estim_acousticstim', '/estim_Nphasebins', '/estim_Npostcycles', ...
                '/estim_acousticstim_offset', '/estim_timePostEAStim', '/estim_stimphase_map', ... % below, fields that have to do with RespVsPhase
                '/rvp_jitter', '/rvp_trialsperstim', '/rvp_stimseparation', '/rvp_stimtrial_map' };
            for k = 1 : numel( otherfields )
                fieldnow = otherfields{k};
                h5create( [fullname, '.h5'], fieldnow, [1 1] );
                eval( sprintf( 'data2write = %s;', fieldnow(2:end) ) );
                try
                    h5write( [fullname, '.h5'], fieldnow, data2write );
                catch
                    2+2;
                end
            end
            
            % store the protocol
            h5create( [fullname, '.h5'], '/curr_protocol', [1 1], 'Datatype', 'string' );
            h5write( [fullname, '.h5'], '/curr_protocol', string( curr_protocol ) );
        end
        
        function ret = calibrate( app, x_orig, x_freq, target_dB_SPL )
            
            % this calibrates rather naively now. I have so far calibrated
            % this speaker with a single 94 dB SPL tone, at a certain
            % frequency (I forgot, let's make it 20 KHz for now). The idea is
            % use this a the reference and then use the curve to adjust to
            % such reference.
            % In simple terms, we assume that at this reference freq.,
            % we're giving 94 dB SPL.. we go from there.
            
            % get me the dBs that the curve has at the reference
            % (calibrator) frequency...
            calibrator_freq = 1; % in kHz...
            calibrator_dbSPL = 94; % in dbSPL.. necessary somethings
            
            % get the dBs from the curve.. whatever this is, we assume, as
            % said, that the speaker outputs here 94 dB SPL
            refdB_inCcurve = interp1( app.cal_f, app.cal_curve, calibrator_freq );
            % how far is the target dB SPL we wanna give?
            % first, figure out for the frequency of stimulation
            dB_stimfreq = interp1( app.cal_f, app.cal_curve, x_freq );
            % what's the difference?
            dB_diff_refGoal = dB_stimfreq - refdB_inCcurve;
            
            % load the calibration pulse from the BK device..
            [ctone, cfs] = audioread( 'BKcalib_pulse_210423.wav' );
            % use seconds 5 to 8
            ctone = ctone( [ceil(5 * cfs) : ceil(8 * cfs)] );
            
            % find the dBs o x_orig's frequency..
            % normalize this to 94 dBSPL from the calibrator
            [ norm_x, adjust_x, adjust_factor ] = ...
                normalize_RMS( x_orig, ctone, calibrator_dbSPL, target_dB_SPL - dB_diff_refGoal );
                        % subtraction here because I need to attenuate if
                        % the diff is positive. In a flat curve,
                        % target_dB_SPL would be on it's own there...
            
            ret = adjust_x;
        end
        
        function [ Nstims, allstim_duration, ret_wavstims, ret_stimsamples ] = load_stims_from_folder( app, wav_path )
        
            % Loads the stims from a folder, returns them.
            wav_path = app.datapath_soundbattery;
            Nstims = numel( app.wavfiles ) .* app.Ntrials;
            
            ret_wavstims = {};
            ret_stimsamples = {};
            
            % load all the stimuli
            allstim_duration = 0;
            for kk = 1 : numel( app.wavfiles )
                fname = app.wavfiles(kk).name;
                % load the file
                [x, fswav] = audioread( [app.datapath_soundbattery, '\', fname] ); x = x';
                % should it be resampled?
                if ( app.fs ~= fswav )
                    x = resample( x, app.fs, fswav ); end % resample
                ret_wavstims{kk} = x;
                ret_stimsamples{kk} = numel(x);
                allstim_duration = allstim_duration + ret_stimsamples{kk};
            end
        end
        
        function [] = add_synctone( app, tstart, len, freq, factor )
            
            % make the tone...
            ttone = [ 0 : ceil( len * app.fs ) ] ./ app.fs;
            tone = sin( 2 * pi * freq .* ttone ) .* factor;
            
            % information for the ttl trigger
            app.TTL_duration = app.txt_TTLDur.Value;
            TTLsamples = ceil( app.fs * app.TTL_duration );
            
            % add the tone to channel one of the stim_protocol
            sstart = ceil(tstart * app.fs); send = sstart + numel(tone) - 1;
            app.stim_protocol( sstart : send,  1 ) = tone';
            
            % add the trigger to the ACOUSTIC trigger (i.e. the one that
            % goes to the multichannel system)
            send = sstart + TTLsamples - 1;
            app.stim_protocol( sstart : send, 2 ) = ones( TTLsamples, 1 );
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Button pushed function: but_LoadStimFolder
        function but_LoadStimFolderPushed(app, event)
            
            clc;
            
            %% loading the folder with the sound battery
            
            % check whether there's a path variable in the folder...
            if ( isfile( 'path_soundbattery.mat' ) )
                load path_soundbattery.mat;
                path_soundbattery = uigetdir( path_soundbattery, 'Select wav folder' );
            else
                path_soundbattery = uigetdir( '', 'Select wav folder' );
            end
            
            if ( path_soundbattery == 0 ) path_soundbattery = ''; end % just in case
            % save in the app properties..
            app.datapath_soundbattery = path_soundbattery;
            % save in matlab variable outside
            save path_soundbattery path_soundbattery;
            
            %% getting the wav file names...
            % get the wavfiles
            app.wavfiles = dir( [ app.datapath_soundbattery, '\', '*.wav' ] );
            
            % update all status
            app.lbl_NumStimLoaded.Text = sprintf( 'Loaded %d wav files', numel( app.wavfiles ) );
            app.txt_SoundBatteryPath.Value = path_soundbattery;
            app.lbl_Status.Text = 'Loaded Stims for sound battery';
            
        end

        % Button pushed function: but_Generate
        function but_GenerateButtonPushed(app, event)
            
            % Generates the frequency tuning stimuli.
            
            % get the frequencies...
            app.ft_stims = {}; % initialize
            app.ft_freqs = [ str2num( app.txt_FreqStart.Value ) : str2num( app.txt_FreqStep.Value ) : str2num( app.txt_FreqEnd.Value ) ];
            % get the levels
            app.ft_levels = [ str2num( app.txt_dBSPLStart.Value ) : str2num( app.txt_dBStep.Value ) : str2num( app.txt_dBSPLEnd.Value ) ];
            
            % generate all the stims at various freqs.. given duration...
            % I'll add the amplitude part later, when I figure out how to
            % do the calibration...
            
            % some properties of the tones...
            L = str2num( app.txt_FTDuration.Value );
            app.fs = app.txt_Fs.Value;
            t = [ 0 : 1/app.fs : L ];
            app.ramp_duration = str2num( app.txt_RampDuration.Value );
            
            % generate per freq now...
            for m = 1 : numel( app.ft_freqs )
                
                freqnowKHz = app.ft_freqs( m );
                % generate a cosine with duration L, and specific freq
                tone_now = cos( 2 * pi * (freqnowKHz*1000) .* t );
                % check if multiply the stim by a ramp...
                if ( app.ramp_duration > 0 )
                   tone_now = app.ramp_stimulus_onoff( tone_now ); end
               
                for mm = 1 : numel( app.ft_levels )
                    TONE_NOW_CAL = app.calibrate( tone_now, app.ft_freqs(m), app.ft_levels(mm) ); % this needs to be removed
                    app.ft_stims{m, mm} = TONE_NOW_CAL;
                end
            end
            
            2+2;
        end

        % Button pushed function: but_RemoveStimType
        function but_RemoveStimTypePushed(app, event)
            
            % Removes the seleted Stimulation type from the drop_down
            % optionss..
            itemsNow = app.StimulationTypeDropDown.Items;
            item_selected = app.StimulationTypeDropDown.Value;
            
            if ( numel( itemsNow ) == 1 ) return; end;
            
            % find the idx of the selected, remove it...
            idxnow = find( string(item_selected) == string( itemsNow ) );
            % and remove it as easily as possible
            % check if idx is not on the edge.. then it's easy
            if ( idxnow ~= 1 & idxnow < numel( itemsNow ) )
                itemsNow = itemsNow( [ 1 : idxnow - 1, idxnow + 1 : numel(itemsNow) ] );
            elseif idxnow == 1
                itemsNow = itemsNow( 2 : end );
            else
                itemsNow = itemsNow( 1 : end-1 );
            end
            
            app.StimulationTypeDropDown.Items = itemsNow;
            
        end

        % Button pushed function: but_AddStimType
        function but_AddStimTypeButtonPushed(app, event)
            
            % Adds a stimulation type (in case that you want it to be
            % 'DelayTuning' and/or 'SyllTracking' (examples) instead of
            % 'SoundBattery', which is pretty damn unspecific...
            itemsNow = app.StimulationTypeDropDown.Items;
            item2add = app.txt_StimType2Add.Value;
            itemsNow{end + 1} = item2add;
            app.StimulationTypeDropDown.Items = itemsNow;
        end

        % Button pushed function: GenerateFullStimulationButton
        function GenerateFullStimulationButtonPushed(app, event)
            
            % This generates the indexing paradigm for presenting
            % stimuli... Ultimately, from here come the protocol files that
            % will let me know what stimulus was presented....

            % some BASIC init
            randstim_idx = []; 
            samples_triggers = [];
            stimPhasesIdList = [];
            trigpos_estimNoAudio = [];
            
            % start delay, end delay. Simply to not start with a trigger
            % right on the click. Needs to be incorporated to some of the
            % protocols here...
            start_delay = ceil( 5 * app.fs ); % in samples, wait 5 seconds.
            end_delay = ceil( 5 * app.fs ); % ^^ same stuff
            
            % gather some info
            app.pre_time = str2num( app.txt_PreTime.Value );
            app.post_time = str2num( app.txt_PostTime.Value );
            app.ISI = str2num( app.txt_IStimI.Value );
            app.fs = app.txt_Fs.Value;
            app.Ntrials = str2num( app.txt_Ntrials.Value );
            
            nsamples_pre = ceil( app.fs * app.pre_time );
            nsamples_post = ceil( app.fs * app.post_time );
            nsamples_ISI = ceil( app.fs * app.ISI );
            
            addtrigger2signal = 1;
            app.TTL_duration = app.txt_TTLDur.Value;
            TTLsamples = ceil( app.fs * app.TTL_duration );
            
            % first, let's generate for the frequency tuning (generated
            % sounds inside of the app itself)
            app.running_protocol = app.StimulationTypeDropDown.Value;
            
            if ( strcmp( app.running_protocol, 'FreqTuning' ) )
                
                % ..............frequency tuning..........
                
                Nstims = prod( size( app.ft_stims ) ); % frequency x levels
                randstim_idx = [];
                for kk = 1 : app.Ntrials
                    randstim_idx = [ randstim_idx, randperm( Nstims ) ];
                end
                
                % yet another layer of random
                randstim_idx = randstim_idx( randperm(numel(randstim_idx)) );
                
                [freqId, levelId] = ind2sub( size(app.ft_stims), randstim_idx );
                Nstims = numel(freqId);
                
                % concatenate the frequency tuning stimuli to present...
                % init some things.. start from the second stim...
                % the full size of the stim.. pre-allocate so that it
                % doesn't take so much time to generate...
                samples_triggers = nan( 1, Nstims );
                app.stim_protocol = zeros( 1, ...
                    ( numel( app.ft_stims{1,1} ) + nsamples_ISI + nsamples_post + nsamples_pre ) * Nstims - 1 + ...
                      numel( app.ft_stims{1,1} ) + nsamples_pre + nsamples_post );
                
                % adding up the first stimulus
                samples_triggers(1) = 1;
                x = app.ft_stims{ freqId(1), levelId(1) };
                app.stim_protocol( 1 : nsamples_pre + numel( app.ft_stims{1,1} ) + nsamples_post ) = ...
                    [ zeros(1, nsamples_pre), x, zeros(1, nsamples_post) ]; % the first stimulus
                
                end_of_first = nsamples_pre + numel( app.ft_stims{1,1} ) + nsamples_post; % end of first stimulus
                nsamples_stim = numel(x); % stimuli have always the same length in freq tuning...
                
                for kk = 2 : numel( freqId )
                    
                    x = app.ft_stims{ freqId(kk), levelId(kk) };
                    x_ISIprepost = [ zeros(1, nsamples_ISI), zeros(1, nsamples_pre), x, zeros(1, nsamples_post) ];
                    
                    sample_start = ( end_of_first ) + ...
                                   ( kk - 2 ) * (nsamples_ISI + nsamples_stim + nsamples_pre + nsamples_post) + 1;
                    sample_end = sample_start + (nsamples_ISI + nsamples_stim + nsamples_pre + nsamples_post) - 1;
                    
                    % add to the stimlulus ensemble
                    app.stim_protocol( sample_start : sample_end ) = x_ISIprepost;
                    
                    % get me where the triggers are happening...
                    samples_triggers(kk) = sample_start + nsamples_ISI; % it's where the stim (with pre and post) start...
                end

            elseif ( strcmp( app.running_protocol, 'SoundBattery' ) )
            
                % Sound battery protocol.. This here is when stimuli come
                % from file in a folder... Load the stimuli in the files,
                % then check if they have the same fs as what we have
                % here (and fix).. just concatenate them again as we did
                % with the freq tuning...
                
                [ Nstims, allstim_duration, app.wavstims, stim_samples ] = ...
                        app.load_stims_from_folder( app.datapath_soundbattery );
                
                % now that we have all the stimuli, let's reorganize them
                randstim_idx = [];
                for m = 1 : app.Ntrials
                    randstim_idx = [ randstim_idx, ...
                                     randperm( numel( app.wavstims ) ) ];
                end

                % how long does a stimulus block take (a block is a
                % presentation of all N wav files)
                block_duration = allstim_duration + ...
                                 ( nsamples_ISI + nsamples_pre + nsamples_post ) * numel( app.wavstims );
                                 
                % pre-allocate for the full protocol super-stimulus variable
                app.stim_protocol = zeros( 1, block_duration * app.Ntrials );
                
                % consturct the super-stimulus of the protocol
                last_sample = 0;
                samples_triggers = nan( 1, Nstims );
                
                for kk = 1 : Nstims
                    
                    x = app.wavstims{ randstim_idx(kk) };
                    x_ISIprepost = [ zeros(1, nsamples_ISI), zeros(1, nsamples_pre), x, zeros(1, nsamples_post) ];
                    
                    sample_start = last_sample + 1;
                    sample_end = sample_start + numel( x_ISIprepost ) - 1;
                    
                    % add the current wav to the super-stimulus... (with
                    % all included)
                    app.stim_protocol( sample_start : sample_end ) = x_ISIprepost;
                    % save where the trigger is happening...
                    samples_triggers(kk) = sample_start + nsamples_ISI;
                    
                    % update last sample to use with next wav
                    last_sample = sample_end;
                end
            elseif ( strcmp( app.running_protocol, 'ElectricalStim' ) )
            
                % This part generates stimulation for the electrical
                % outputs. The first thing that needs to be done is to load
                % the stimulus (or stimuli) that will be used for acoustic
                % stimulation AFTER the electrical stimulation is done. The
                % electrical stimulation is supposed to "entrain" so that
                % one can check whether the phases of such entrainment
                % alter the strenght of responses.
                %
                % However, there's no need to make acoustic stimulation if
                % the checkbox "Acoustic Stimulation" is unmarked. That
                % means that I'll wait some time after the last electrical
                % input and call it a trial. IF the checkbox is marked,
                % then I'll deal the stimuli, etc...
                %
                % This will force me to make the app work with three output
                % channels, won't it? hehehe yes
                %
                % Okay, what I'm about to do is not pretty, but it's the
                % fastest way to code this at the moment and I don't have
                % time to be fancy or a purist. Plus, it works.. so fuck
                % you (yeah I talk to myself). Big CONDITINAL coming to
                % switch between a case when I want an acoustic stimulus
                % after the electrical pulses or not.
                
                app.estim_noacousticstim = app.check_NoAcousticStim.Value;
                
                % gather some information that always works
                app.estim_rate = str2double( app.txt_eStimRate.Value );
                app.estim_nperblock = str2double( app.txt_NeStim.Value );
                app.estim_timePostEAStim = str2double( app.txt_TimePostEAStim.Value ); 
                % make a block of the electrical triggers (basis timing)
                estim_period_samps = ceil( 1 / app.estim_rate .* app.fs );
                estim_trigger_basepos = [ 0 : estim_period_samps : (app.estim_nperblock-1) * estim_period_samps ];
                    
                if ( app.estim_noacousticstim )  % obviously, this is pure electrical stimulation. no audio to animal
                    
                    % get some stuff
                    app.estim_noacoustic_ntrials = str2double( app.txt_eStimNtrialsNoAudio.Value );
                    app.estim_noacoustic_stimsep = str2double( app.txt_eStimNoAudioSeparation.Value );
                    
                     % First of all, let's generate a basis for the triggers of
                    % the electrical stimulation...
                    app.estim_rate = str2double( app.txt_eStimRate.Value );
                    app.estim_nperblock = str2double( app.txt_NeStim.Value );
                    app.estim_timePostEAStim = str2double( app.txt_TimePostEAStim.Value );
                    
                    stim_sepsamples = ceil( app.estim_noacoustic_stimsep * app.fs );
                    % now let's do the litte spacing
                    spacenow = ceil( app.estim_noacoustic_stimsep * app.fs ) + estim_trigger_basepos(end);
                    trigpos_estimNoAudio = [ 0 : spacenow : spacenow * (app.estim_noacoustic_ntrials - 1) ]; % position of the first stimulatino trigger
                    % add the start delay...
                    trigpos_estimNoAudio = trigpos_estimNoAudio + start_delay;
                    
                    % alright.. so how does the actual trigger signal look
                    % like in a sigle block?
                    block_duration = estim_trigger_basepos(end) + TTLsamples+1;
                    base_triggersE = zeros( block_duration, 1 );
                    % construct the actual electric stimulation triggers
                    for m = 1 : numel( estim_trigger_basepos )
                        base_triggersE( 1+[estim_trigger_basepos(m) : estim_trigger_basepos(m) + TTLsamples - 1] ) = ... % starts at 0, so needs 1+ correction
                                    ones( 1, TTLsamples );
                    end
                    
                    % and now just make a big stimulation sound and simply
                    % add those triggers there... first, tell the app not to
                    % add triggers later
                    addtrigger2signal = 0;
                    
                    fulllength =spacenow * (app.estim_noacoustic_ntrials - 1) + end_delay;
                    app.stim_protocol = zeros( fulllength, 3 );
                    % go through the calculated trigger positions.. add the
                    % trigger block
                    for kk = 1 : numel( trigpos_estimNoAudio )
                        start_sample = trigpos_estimNoAudio(kk);
                        end_sample = start_sample + numel(base_triggersE) - 1;
                        app.stim_protocol( start_sample:end_sample, 3 ) = base_triggersE';
                    end

                    app.stim_protocol( :, 2 ) = app.stim_protocol( :, 3 );  % so that I can see the electrical stimulation triggers in the recording system too
                    
                    % add to the app.stim_protocol, 2 seconds in, a 500 ms,
                    % 5 kHz tone that helps me align the ephys with the
                    % audio recording...
                    app.add_synctone( 2, .5, 2500, 7e-3 );
                else
                    
                    % Essentially here is the code for what happens when
                    % I want indeed an acoustic stimulus after electricity
                    % is passed :P

                    % make out the acoustic triggers positions that come after the electrical
                    % stimuli...
                    estim_Atrigger_basepos = []; % acoustic trigger postiions of electrical stimulation             
                    % define when the stimuli are to occur
                    app.estim_Nphasebins = str2double( app.txt_phaseBinsEStim.Value );
                    app.estim_Npostcycles = str2double( app.txt_PostCyclesEStim.Value );
                    % set the basic time points when these stimuli should
                    % happen. Time 0 in this case is the moment immediately
                    % after the stimulation was over, plus some offset in
                    % millisecond to avoid overlap...
                    app.estim_acousticstim_offset = str2double( app.txt_EAStimOffset.Value );
                    offsamples = ceil( app.estim_acousticstim_offset * app.fs );
                    % how many samples in the number of cycles after?
                    nsamps_cycles = estim_period_samps * app.estim_Npostcycles;
                    % how many samples should they be separated so that I
                    % can get Nphasebins points?
                    phasebin_sep = floor( estim_period_samps ./ app.estim_Nphasebins );
                    estim_Atrigger_basepos = [ 0 : phasebin_sep : nsamps_cycles ] + offsamples;
                    
                    % how much does a full block last?
                        % a block lasts the electrical stim and how much I wait
                        % after that.. regardless of the stimuli. Stimuli
                        % should be short (otherwise the overlap each other),
                        % short in comparison to the period and the number of
                        % bins I check. So waiting a few secons after the last
                        % electrical pulse should include everyting...
                    % here, a block is 1 stim round (unlike above).
                    block_duration = estim_trigger_basepos(end) + ceil( app.estim_timePostEAStim * app.fs ) + ...
                                    ( nsamples_ISI + nsamples_pre + nsamples_post );
                    
                    % make the trigger stuff here. first, tell the app not to
                    % add triggers later
                    addtrigger2signal = 0;
                    
                    % alright.. so how does the actual trigger signal look
                    % like in a sigle block?
                    base_triggersE = zeros( block_duration, 1 );
                    
                    % construct the actual electric stimulation triggers
                    for m = 1 : numel( estim_trigger_basepos )
                        base_triggersE( 1+[estim_trigger_basepos(m) : estim_trigger_basepos(m) + TTLsamples - 1] ) = ... % starts at 0, so needs 1+ correction
                                    ones( 1, TTLsamples );
                    end
                    
                    % I need to load the sounds...
                    [ Nstims, allstim_duration, app.wavstims, stim_samples ] = ...
                        app.load_stims_from_folder( app.datapath_soundbattery );
                    
                    % now here comes the really funny thing, because sounds
                    % need to be presented at different relative phases,
                    % exactly Ntrials times. We'll see how we mix those. In the
                    % end I should have nwavs * Ntrials * nphases
                    % combinations...
                    
                    % let's make a little matrix with all stimuli and their
                    % phases...
                    stimPhasesIdList = [ repmat( 1 : numel(app.wavstims), 1, numel(estim_Atrigger_basepos) ); ...
                                         repelem( 1 : numel(estim_Atrigger_basepos), numel(app.wavstims) ) ];
                    
                    % now that we have all the stimuli, let's reorganize them
                    randstim_idx = [];
                    for m = 1 : app.Ntrials
                        randstim_idx = [ randstim_idx, ...
                                         randperm( size( stimPhasesIdList, 2 ) ) ];
                    end  
                    
                    % how much does the whole thing last?
                    % pre-allocate for the full protocol super-stimulus variable
                    app.stim_protocol = zeros( block_duration * numel( randstim_idx ) + end_delay, 3 );
                    
                    % okay, so let's put these stimuli where they go, and move
                    % throught trials...
                    last_sample = start_delay;
                    for kk = 1 : numel( randstim_idx )
                        
                        stimidx = randstim_idx(kk);
                        x = app.wavstims{ stimPhasesIdList( 1, stimidx ) };
                        phase2use = stimPhasesIdList( 2, stimidx );
                        
                        % what is the offset in the sound offset corresponding to this phase?
                        phase_offset = estim_Atrigger_basepos(phase2use);
                        % what does a full stimulus block look like here?
                        % start with the acoustic trigger (acoustic trigger
                        % occurs at phase_offset + the last time when an
                        % electric trigger occurred)
                        start_Atrigger = phase_offset + estim_trigger_basepos(end); % when the acoustic trigger starts... 
                        base_triggersA = base_triggersE; % so that I can see the electrical stimulation triggers also in the recording system
                        base_triggersA( start_Atrigger : start_Atrigger + TTLsamples - 1 ) = ones( 1, TTLsamples );
                        
                        xWithAll = zeros( size( base_triggersA ) );
                        xWithAll( start_Atrigger : start_Atrigger + numel(x) - 1 ) = x;
                        
                        sample_start = last_sample + 1;
                        sample_end = sample_start + numel( xWithAll ) - 1;
                        
    %                     figure(1); plot( [1: numel(base_triggersE)]./app.fs, base_triggersE );
    %                     hold on; plot( [1: numel(base_triggersA)]./app.fs, base_triggersA );
    %                     plot( [1: numel(base_triggersA)]./app.fs, xWithAll );
                        
                        % okay okay.. now, let's add this to the super
                        % stimulus...
                        app.stim_protocol( sample_start : sample_end, : ) = ...
                                [ xWithAll, base_triggersA, base_triggersE  ];
                        
                        % update for next iteration. make my life easier
                        last_sample = sample_end;
                    end
                    
                    % add to the app.stim_protocol, 2 seconds in, a 500 ms,
                    % 5 kHz tone that helps me align the ephys with the
                    % audio recording...
                    app.add_synctone( 2, .5, 2500, 7e-3 );
                end
                
            elseif ( strcmp( app.running_protocol, 'ResponseVsPhase' ) ) % back to the big if to check for protocol type
            
                % The idea of this protocol is to present stimuli at random
                % times to check if the response to them depends on
                % LFP phase. Let's get on to it...
                
                % just define some values here
                app.rvp_jitter = str2num( app.txt_RvPJitter.Value );
                app.rvp_trialsperstim = str2double( app.txt_RvPStimTrials.Value );
                app.rvp_stimseparation = str2double( app.txt_RvPStimSeparation.Value );
               
                % let's load the sounds                
                [ ~, allstim_duration, app.wavstims, stim_samples ] = ...
                    app.load_stims_from_folder( app.datapath_soundbattery );
                Nstims = numel( app.wavstims );
                
                % now let's generate where is it that the acoustic triggers
                % need to occur....
                Ntriggers = Nstims * app.rvp_trialsperstim;
                samples_triggers = ceil( linspace( 0, app.rvp_stimseparation * Ntriggers, Ntriggers ) );
                % let's add some jitter to those triggers
                samples_triggers(2:end) = samples_triggers(2:end) + ...
                                    ( app.rvp_jitter(2) - app.rvp_jitter(1) ) .* rand( size(samples_triggers(2:end)) ) + app.rvp_jitter(1);
                % wait some time before first trigger
                samples_triggers = ceil( samples_triggers .* app.fs ); % make them into samples first..
                samples_triggers = samples_triggers + start_delay; 

                % now things are easy. we have a few wavs, let's
                % distribute them across those triggers, randomly
                app.rvp_stimtrial_map = repelem( [1 : Nstims], app.rvp_trialsperstim );
                app.rvp_stimtrial_map = app.rvp_stimtrial_map( randperm( numel(app.rvp_stimtrial_map) ) );
                
                % what is the longest that this will go? preallocate while answering
                fulllength = samples_triggers(end) + stim_samples{ app.rvp_stimtrial_map(end) } + end_delay;
                app.stim_protocol = zeros( 1, fulllength );
                
                % now, life is easy.. let's simply put the stimuli in the
                % postions where they belong
                for kk = 1 : numel( app.rvp_stimtrial_map )
                    
                    x = app.wavstims{ app.rvp_stimtrial_map(kk) };
                    
                    % where should this start? check the trigger that
                    % corresponds to it
                    sample_start = samples_triggers(kk);
                    sample_end = sample_start + numel(x) - 1;
                    
                    app.stim_protocol( sample_start : sample_end ) = x;
                end
            end
            
            % now, the trigger needs to be added to the signal....
            if (addtrigger2signal)
                
                trigger_channel = zeros( size( app.stim_protocol ) ); % here, stim protocol should be only the stim...
                for m = 1 : numel( samples_triggers )
                    trigger_channel( samples_triggers(m) : samples_triggers(m) + TTLsamples - 1 ) = ...
                            ones( 1, TTLsamples ); % trigger is negative for neuropixels SpikeGLX
                end
                
                app.stim_protocol = [ app.stim_protocol', trigger_channel', zeros( numel(trigger_channel), 1 ) ];
                % the third channel up here ^ is the electrical stimulation
                % trigger that I added anyways (although here it's not
                % supposed to be electrical stimulation) so that my life is
                % easier later.
            end
            
            % how about updating the total lenght?
            app.lbl_RvPTotalLength.Text = sprintf( 'Total length (min): %.2f', size(app.stim_protocol, 1) / app.fs / 60 );
            
            % How about we construct a table with the current stimulation
            % conditions? Let's do it.. it'll be a function
            app.protocol_table = app.build_protocol_table( app.running_protocol, randstim_idx, samples_triggers, ...
                                       stimPhasesIdList, trigpos_estimNoAudio, ... % things that have to do with electrical stimulation
                                        app.rvp_stimtrial_map ); % things that have to do with response vs phase stuff
        end

        % Button pushed function: but_ChangeSavePath
        function but_ChangeSavePathButtonPushed(app, event)
            
            %% Chaning the folder where the protocol meatada is saved...
            
            clc;
            
            % check whether there's a saving path file
            if ( isfile( 'path_protmetadata.mat' ) )
                load path_protmetadata;
                path_protmetadata = uigetdir( path_protmetadata, 'Select path to metadata protocol files' );
            else
                path_protmetadata = uigetdir( '', 'Select path to metadata protocol files' );
            end
            
            if ( path_protmetadata == 0 ) path_protmetadata = ''; end;
            % save in the app properties
            app.datapath_protmetadata = path_protmetadata;
            % save in a file for later
            save path_protmetadata path_protmetadata;
            
            % update the path text
            app.txt_ProtFilePath.Value = path_protmetadata;
        end

        % Button pushed function: but_SaveStimMetadata
        function but_SaveStimMetadataButtonPushed(app, event)
            % This simply calls the function of writing to the metadata
            % file... gives me an excuse to write it right now..
            app.save_prot_metadata_file();
        end

        % Callback function
        function PlaySoundsButtonPushed(app, event)
            
            %% This is the button that deals with the stimulation...
            % For now, what it should be doing is playing a sound and
        end

        % Button pushed function: but_PlaySounds
        function but_PlaySoundsButtonPushed(app, event)
            
            clc;
            %% This goes ahead and plays the stimuli...
            
            % Input and output channels are read from the GUI. If there are
            % more than one, they should be separated by ';'. E.g., if you
            % want inputs 1 and 3, you should write (1;2) in the input
            % channel text box.
            
            % Note that the second channel you mention should be the one
            % where the trigger goes (usually you'd be playing stereo)..
            
            % The samples per frame are also read from the GUI.
            
            input_channels = str2num( app.txt_InputChs.Value );
            output_channels = str2num( app.txt_OutputChs.Value );
            samples_per_frame = str2num( app.txt_SamplesPerFrame.Value );
            
            % check if there are input channels.. if not, then I don't need
            % a playrec object, but simply something to play sounds (and
            % not record).. for example, passive stimulation should be done
            % like that.... (if you don't wanna check anything)
            
            if ( isempty( input_channels ) | ( numel(input_channels) == 1 & input_channels == -1 ) )
                
                % ---SIMPLY PLAYING, NOT RECORDING FROM DEVICE-------
                
                deviceWriter = audioDeviceWriter( 'SampleRate', app.fs, 'Driver', 'ASIO' );
                devices = getAudioDevices( deviceWriter );
                
                % take the device to use from the GUI
                deviceWriter.Device = devices{ str2num( app.txt_AudioDevice.Value ) };
                deviceWriter.ChannelMapping = output_channels;
                deviceWriter.SupportVariableSizeInput = 1;
                
                % I have to play the stim_protocol, all chuncked up...
                setup( deviceWriter, zeros( samples_per_frame, numel(output_channels) ) );
                
                tot_samples = size( app.stim_protocol, 1 );
                for it = 1 : ceil( tot_samples / samples_per_frame )     
                    sample_start = (it - 1) * samples_per_frame + 1;
                    sample_end = min( it * samples_per_frame, tot_samples );
                    deviceWriter( app.stim_protocol( sample_start : sample_end, : ) );
                end
                
                % clear th  e device
                release( deviceWriter );
                
            elseif ( ( ~isempty( input_channels & ~isempty( output_channels ) ) ) | ...
                     ( sum(input_channels == -1) < numel(input_channels) & sum(output_channels == -1) < numel(output_channels) ) )
                
                % SO MUCH ELABORATE CRAP JUST TO MAKE SURE OF A FEW THINGS
                % AND GUARANTEE THAT THE INPUT AND OUTPUT CHANNELS EXIST...
                % THE -1 ARE UNNECESSARY, TO BE FAIR... BUT ANYWAYS.. this
                % makes a playrec object to play sounds and record
                % simultaneously...
                
                % create the device controller
                playrec = audioPlayerRecorder( 'SampleRate', app.fs );
                playrec.SupportVariableSize = 1;
                devices = getAudioDevices( playrec );
                
                % take the device from the GUI
                playrec.Device = devices{ str2num( app.txt_AudioDevice.Value ) };
                playrec.PlayerChannelMapping = output_channels;
                playrec.RecorderChannelMapping = input_channels;
                
                % I have to play the stim_protocol, all chuncked up...
                setup( playrec, zeros( samples_per_frame, numel(output_channels) ) );
                
                % but I also have to do a filewriter
                wavfilepath = app.txt_RecWavFilePath.Value;
                wavbasename = app.txt_RecWavBaseName.Value;
                wavidx = app.txt_RecWavIndex.Value;
                recwav_fullpath = [ wavfilepath, '\', wavbasename, wavidx, '.wav' ];
                fileWriter = dsp.AudioFileWriter( recwav_fullpath, 'SampleRate', app.fs );
                
                % from now on, there are 2 types of possible ways: 1, I
                % present stimuli and record together, or I do one tone as
                % trigger to synchronize vocalizations and neural data...
                % (if check-box for only vocalizing bat is on)...
                tot_samples = size( app.stim_protocol, 1 );
                if ( ~app.check_FreelyVocProtocol.Value )
                    
                    % The easy one: playing a the full stimulus and
                    % recording at the same time...
                    for it = 1 : ceil( tot_samples / samples_per_frame ) 
                        sample_start = (it - 1) * samples_per_frame + 1;
                        sample_end = min( it * samples_per_frame, tot_samples );
                        [audioRecorded, nunderrun, noverrun] = ...
                            playrec( app.stim_protocol( sample_start : sample_end, : ) );
                        fileWriter( audioRecorded );
                    end
                    
                else
                    
                    % Slightly more annoying one: when freely voc is
                    % required.. I have to come up with a tone, and use it
                    % with a TTL trigger to sync vocalizations with
                    % ephys...  
                    
                    % gather metadata for the freely-voc protocol
                    fullrec_dur = str2double( app.txt_FreeVocRecDuration.Value ) * 60; % was in min...
                    synctone_dur = str2double( app.txt_SyncToneDuration.Value );
                    synctone_freq = str2double( app.txt_SyncToneFreq.Value );
                    
                    % add the synchronization tone to the stimulus
                    t_synctone = [ 0 : 1 ./ app.fs : synctone_dur ];
                    synctone = cos( 2 .* pi .* synctone_freq .* t_synctone );
                    dummy_stim2play = zeros( ceil( app.fs * fullrec_dur ), 2 );
                    dummy_stim2play( ceil(5*app.fs) : ceil(5*app.fs) + numel(synctone) - 1, 1 ) = ...
                        synctone;
                    
                    % add the trigger to the 2nd channel...
                    dummy_stim2play( ceil(5*app.fs) : ceil(5*app.fs) + ceil(app.fs*app.TTL_duration) - 1, 2 ) = ...
                        ones( ceil(app.fs*app.TTL_duration), 1 ) .* -1; % TTL goes down with neuropixels SpikeGLX
                    
                    % okay, now basically I have the dummy stim to play..
                    % the rest is to simply play and record
                    tot_samples_dummy = size( dummy_stim2play, 1 );
                    for it = 1 : ceil( tot_samples_dummy / samples_per_frame ) 
                        sample_start = (it - 1) * samples_per_frame + 1;
                        sample_end = min( it * samples_per_frame, tot_samples_dummy );
                        [audioRecorded, nunderrun, noverrun] = ...
                            playrec( dummy_stim2play( sample_start : sample_end, : ) );
                        fileWriter( audioRecorded );
                    end
                end
                
                % clear the player and the filewriter
                release( playrec );
                release( fileWriter );
            end
            
            
            % save the metadata on the protocol and increase the index,
            % ready for the next round...
            app.save_prot_metadata_file();
            app.txt_ProtFileIndex.Value = num2str( str2num(app.txt_ProtFileIndex.Value) + 1 );
            
            disp( 'Done playing!' );
        end

        % Button pushed function: but_LoadCalibCurve
        function but_LoadCalibCurveButtonPushed(app, event)
            
            % Loads a calibration curve
            if ( isfile( 'path_calibcurve.mat' ) )
                load path_calibcurve.mat;
                [file, path] = uigetfile( [ path_calibcurve, '\', '*.cal' ], 'Load a calib curve' );
            else
                [ file, path_calibcurve ] = uigetfile( '*.cal', 'Load a calib curve' );
            end
            
            % save in the app properties
            app.datapath_calibcurve = [path, file];
            % save in matlab var outside
            path_calibcurve = path;
            save path_calibcurve.mat path_calibcurve;
            
            % let's load the actual calibration curve
            fid = fopen( app.datapath_calibcurve, 'r' );
            Npoints = fread( fid, 1, 'single' );
            datacal = fread( fid, Npoints * 2, 'single' );
            datacal = reshape( datacal, 2, Npoints );
            % save the stuff in the app
            app.cal_f = datacal( 2, : );
            app.cal_curve = datacal( 1, : );
            
            % plot it in the axes
            plot( app.axes_CalibCurve, app.cal_f, app.cal_curve );
        end

        % Button pushed function: but_ChangeSaveRecWavPath
        function but_ChangeSaveRecWavPathButtonPushed(app, event)
            clc;
            
            % check whether there's a saving path file
            if ( isfile( 'path_recwavdata.mat' ) )
                load path_recwavdata;
                path_recwavdata = uigetdir( path_recwavdata, 'Select where WAV files will be stored' );
            else
                path_recwavdata = uigetdir( '', 'Select where WAV files will be stored' );
            end
            
            if ( path_recwavdata == 0 ) path_recwavdata = ''; end;
            % save in the app properties
            app.datapath_recwavdata = path_recwavdata;
            % save in a file for later
            save path_recwavdata path_recwavdata;
            
            % update the path text
            app.txt_RecWavFilePath.Value = path_recwavdata;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.StimulationParametersPanel = uipanel(app.UIFigure);
            app.StimulationParametersPanel.Title = 'Stimulation Parameters';
            app.check_NoAcousticStim = uicheckbox(app.StimulationParametersPanel);
            app.check_NoAcousticStim.Text = 'No acoustic stim';
            app.ElectricalstimulationthingsLabel = uilabel(app.StimulationParametersPanel);
            app.ElectricalstimulationthingsLabel.Text = '________Electrical stimulation things________________________________';
            app.txt_PreTime = uieditfield(app.StimulationParametersPanel, 'text');
            app.txt_PreTime.Tag = 'txt_PreTime';
            app.txt_PreTime.Value = '0.05';
            app.pretimesEditFieldLabel = uilabel(app.StimulationParametersPanel);
            app.pretimesEditFieldLabel.Text = 'pre-time (s)';
            app.txt_PostTime = uieditfield(app.StimulationParametersPanel, 'text');
            app.txt_PostTime.Tag = 'txt_PostTime';
            app.txt_PostTime.Value = '0.25';
            app.posttimesEditFieldLabel = uilabel(app.StimulationParametersPanel);
            app.posttimesEditFieldLabel.Text = 'post-time (s)';
            app.txt_IStimI = uieditfield(app.StimulationParametersPanel, 'text');
            app.txt_IStimI.Tag = 'txt_ISI';
            app.txt_IStimI.Value = '0.25';
            app.interstimintsEditFieldLabel = uilabel(app.StimulationParametersPanel);
            app.interstimintsEditFieldLabel.Text = 'inter stim int (s)';
            app.txt_RampDuration = uieditfield(app.StimulationParametersPanel, 'text');
            app.txt_RampDuration.Tag = 'txt_PreTime';
            app.txt_RampDuration.Value = '0.5e-3';
            app.rampdursEditFieldLabel = uilabel(app.StimulationParametersPanel);
            app.rampdursEditFieldLabel.Text = 'ramp dur. (s)';
            app.txt_Ntrials = uieditfield(app.StimulationParametersPanel, 'text');
            app.txt_Ntrials.Tag = 'txt_PreTime';
            app.txt_Ntrials.Value = '25';
            app.NtrialsLabel = uilabel(app.StimulationParametersPanel);
            app.NtrialsLabel.Text = 'Ntrials';
            app.txt_Fs = uieditfield(app.StimulationParametersPanel, 'numeric');
            app.txt_Fs.Value = 192000;
            app.fsHzEditFieldLabel = uilabel(app.StimulationParametersPanel);
            app.fsHzEditFieldLabel.Text = 'fs (Hz)';
            app.txt_TTLDur = uieditfield(app.StimulationParametersPanel, 'numeric');
            app.txt_TTLDur.Value = 0.0005;
            app.TTLdursLabel = uilabel(app.StimulationParametersPanel);
            app.TTLdursLabel.Text = 'TTL dur (s)';
            app.txt_eStimRate = uieditfield(app.StimulationParametersPanel, 'text');
            app.txt_eStimRate.Tag = 'txt_ISI';
            app.txt_eStimRate.Value = '2';
            app.estim_rateHzLabel = uilabel(app.StimulationParametersPanel);
            app.estim_rateHzLabel.Text = 'estim_rate (Hz)';
            app.txt_NeStim = uieditfield(app.StimulationParametersPanel, 'text');
            app.txt_NeStim.Tag = 'txt_ISI';
            app.txt_NeStim.Value = '6';
            app.N_estimLabel = uilabel(app.StimulationParametersPanel);
            app.N_estimLabel.Text = 'N_estim';
            app.txt_phaseBinsEStim = uieditfield(app.StimulationParametersPanel, 'text');
            app.txt_phaseBinsEStim.Value = '4';
            app.phaseBinsEditFieldLabel = uilabel(app.StimulationParametersPanel);
            app.phaseBinsEditFieldLabel.Text = 'phaseBins';
            app.txt_PostCyclesEStim = uieditfield(app.StimulationParametersPanel, 'text');
            app.txt_PostCyclesEStim.Value = '2';
            app.postCyclesEditFieldLabel = uilabel(app.StimulationParametersPanel);
            app.postCyclesEditFieldLabel.Text = 'postCycles';
            app.txt_TimePostEAStim = uieditfield(app.StimulationParametersPanel, 'text');
            app.txt_TimePostEAStim.Value = '2.5';
            app.TimeposteAStimsLabel = uilabel(app.StimulationParametersPanel);
            app.TimeposteAStimsLabel.Text = 'Time post-eAStim (s)';
            app.txt_EAStimOffset = uieditfield(app.StimulationParametersPanel, 'text');
            app.txt_EAStimOffset.Value = '10e-3';
            app.EAstimOffsetLabel = uilabel(app.StimulationParametersPanel);
            app.EAstimOffsetLabel.Text = 'EAstimOffset';
            app.txt_eStimNtrialsNoAudio = uieditfield(app.StimulationParametersPanel, 'text');
            app.txt_eStimNtrialsNoAudio.Value = '50';
            app.NTrialsEditFieldLabel = uilabel(app.StimulationParametersPanel);
            app.NTrialsEditFieldLabel.Text = 'NTrials';
            app.txt_eStimNoAudioSeparation = uieditfield(app.StimulationParametersPanel, 'text');
            app.txt_eStimNoAudioSeparation.Value = '10';
            app.StimSepsLabel = uilabel(app.StimulationParametersPanel);
            app.StimSepsLabel.Text = 'StimSep (s)';
            app.FrequencyTuninggeneratesPanel = uipanel(app.UIFigure);
            app.FrequencyTuninggeneratesPanel.Title = 'Frequency Tuning';
            app.FrequencyTuninggeneratesPanel.Tag = 'PanelFreqTuning';
            app.FreqStartkHzEditFieldLabel = uilabel(app.FrequencyTuninggeneratesPanel);
            app.FreqStartkHzEditFieldLabel.Text = 'Freq Start (kHz)';
            app.txt_FreqStart = uieditfield(app.FrequencyTuninggeneratesPanel, 'text');
            app.txt_FreqStart.Tag = 'txt_FreqStart';
            app.txt_FreqStart.Value = '5';
            app.FreqEndEditFieldLabel = uilabel(app.FrequencyTuninggeneratesPanel);
            app.FreqEndEditFieldLabel.Text = 'Freq End';
            app.txt_FreqEnd = uieditfield(app.FrequencyTuninggeneratesPanel, 'text');
            app.txt_FreqEnd.Tag = 'txt_FreqEnd';
            app.txt_FreqEnd.Value = '90';
            app.FreqStepEditFieldLabel = uilabel(app.FrequencyTuninggeneratesPanel);
            app.FreqStepEditFieldLabel.Text = 'Freq Step';
            app.txt_FreqStep = uieditfield(app.FrequencyTuninggeneratesPanel, 'text');
            app.txt_FreqStep.Tag = 'txt_FreqStep';
            app.txt_FreqStep.Value = '5';
            app.LinearfreqgenerationLabel = uilabel(app.FrequencyTuninggeneratesPanel);
            app.LinearfreqgenerationLabel.Text = 'Linear freq generation';
            app.dBSPLStartEditFieldLabel = uilabel(app.FrequencyTuninggeneratesPanel);
            app.dBSPLStartEditFieldLabel.Text = 'dBSPL Start';
            app.txt_dBSPLStart = uieditfield(app.FrequencyTuninggeneratesPanel, 'text');
            app.txt_dBSPLStart.Tag = 'txt_dbSPLStart';
            app.txt_dBSPLStart.Value = '5';
            app.dBSPLEndEditFieldLabel = uilabel(app.FrequencyTuninggeneratesPanel);
            app.dBSPLEndEditFieldLabel.Text = 'dBSPL End';
            app.txt_dBSPLEnd = uieditfield(app.FrequencyTuninggeneratesPanel, 'text');
            app.txt_dBSPLEnd.Tag = 'txt_dbSPLEnd';
            app.txt_dBSPLEnd.Value = '90';
            app.dBStepEditFieldLabel = uilabel(app.FrequencyTuninggeneratesPanel);
            app.dBStepEditFieldLabel.Text = 'dB Step';
            app.txt_dBStep = uieditfield(app.FrequencyTuninggeneratesPanel, 'text');
            app.txt_dBStep.Tag = 'txt_dBStep';
            app.txt_dBStep.Value = '15';
            app.but_Generate = uibutton(app.FrequencyTuninggeneratesPanel, 'push');
            app.but_Generate.ButtonPushedFcn = createCallbackFcn(app, @but_GenerateButtonPushed, true);
            app.but_Generate.Text = 'Generate tones';
            app.durationsLabel = uilabel(app.FrequencyTuninggeneratesPanel);
            app.durationsLabel.Text = 'duration (s)';
            app.txt_FTDuration = uieditfield(app.FrequencyTuninggeneratesPanel, 'text');
            app.txt_FTDuration.Tag = 'txt_dBStep';
            app.txt_FTDuration.Value = '10e-3';
            app.SoundbatterywavfilesPanel = uipanel(app.UIFigure);
            app.SoundbatterywavfilesPanel.Title = 'Sound battery (wav files)';
            app.pathEditFieldLabel = uilabel(app.SoundbatterywavfilesPanel);
            app.pathEditFieldLabel.Text = 'path';
            app.txt_SoundBatteryPath = uieditfield(app.SoundbatterywavfilesPanel, 'text');
            app.txt_SoundBatteryPath.Tag = 'txt_WavFolder';
            app.but_LoadStimFolder = uibutton(app.SoundbatterywavfilesPanel, 'push');
            app.but_LoadStimFolder.ButtonPushedFcn = createCallbackFcn(app, @but_LoadStimFolderPushed, true);
            app.but_LoadStimFolder.Tag = 'but_LoadStimBattery';
            app.but_LoadStimFolder.Text = 'LoadFolder';
            app.lbl_NumStimLoaded = uilabel(app.SoundbatterywavfilesPanel);
            app.lbl_NumStimLoaded.Tag = 'lbl_NSoundsLoaded';
            app.lbl_NumStimLoaded.Text = 'Number of loaded stims';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            app.axes_CalibCurve = uiaxes(app.Panel);
            title(app.axes_CalibCurve, 'CalibrationCurve')
            xlabel(app.axes_CalibCurve, 'X')
            ylabel(app.axes_CalibCurve, 'Y')
            zlabel(app.axes_CalibCurve, 'Z')
            app.calibpathEditFieldLabel = uilabel(app.Panel);
            app.calibpathEditFieldLabel.Text = 'calib. path';
            app.txt_CalibCurvePath = uieditfield(app.Panel, 'text');
            app.txt_CalibCurvePath.Tag = 'txt_PathCalibCurve';
            app.txt_CalibCurvePath.Value = 'D:\Work\Papers\7_AC_FAF_VocalProduction\code\AnalysisRevision1\FAFAC_ElectricalStimulation\stim_software\francisco_first_161027.cal';
            app.but_LoadCalibCurve = uibutton(app.Panel, 'push');
            app.but_LoadCalibCurve.ButtonPushedFcn = createCallbackFcn(app, @but_LoadCalibCurveButtonPushed, true);
            app.but_LoadCalibCurve.Tag = 'but_LoadCalibCurve';
            app.but_LoadCalibCurve.Text = 'LoadCalibCurve';
            app.OriginalllyEstablishedLevdBSPLEditFieldLabel = uilabel(app.Panel);
            app.OriginalllyEstablishedLevdBSPLEditFieldLabel.Text = 'Originallly Established Lev. (dB SPL)';
            app.OriginalllyEstablishedLevdBSPLEditField = uieditfield(app.Panel, 'numeric');
            app.OriginalllyEstablishedLevdBSPLEditField.Value = 32;
            app.lbl_Status = uilabel(app.UIFigure);
            app.lbl_Status.Tag = 'lbl_Status';
            app.lbl_Status.Text = 'Waiting to load / generate stimuli...';
            app.ParadigmdesignPanel = uipanel(app.UIFigure);
            app.ParadigmdesignPanel.Title = 'Paradigm design';
            app.but_RemoveStimType = uibutton(app.ParadigmdesignPanel, 'push');
            app.but_RemoveStimType.ButtonPushedFcn = createCallbackFcn(app, @but_RemoveStimTypePushed, true);
            app.but_RemoveStimType.Text = 'RemoveCurrType';
            app.txt_StimType2Add = uieditfield(app.ParadigmdesignPanel, 'text');
            app.txt_StimType2Add.Value = 'DTuning';
            app.but_AddStimType = uibutton(app.ParadigmdesignPanel, 'push');
            app.but_AddStimType.ButtonPushedFcn = createCallbackFcn(app, @but_AddStimTypeButtonPushed, true);
            app.but_AddStimType.Text = 'AddType';
            app.StimulationTypeDropDownLabel = uilabel(app.ParadigmdesignPanel);
            app.StimulationTypeDropDownLabel.Text = 'Stimulation Type';
            app.StimulationTypeDropDown = uidropdown(app.ParadigmdesignPanel);
            app.StimulationTypeDropDown.Items = {'FreqTuning', 'SoundBattery', 'ElectricalStim', 'ResponseVsPhase'};
            app.StimulationTypeDropDown.Value = 'ResponseVsPhase';
            app.GenerateFullStimulationButton = uibutton(app.UIFigure, 'push');
            app.GenerateFullStimulationButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateFullStimulationButtonPushed, true);
            app.GenerateFullStimulationButton.Text = 'GenerateFullStimulation';
            app.ProtocolSavingFileInformationPanel = uipanel(app.UIFigure);
            app.ProtocolSavingFileInformationPanel.Title = 'Protocol Saving File Information';
            app.SavingPathEditFieldLabel = uilabel(app.ProtocolSavingFileInformationPanel);
            app.SavingPathEditFieldLabel.Text = 'SavingPath';
            app.txt_ProtFilePath = uieditfield(app.ProtocolSavingFileInformationPanel, 'text');
            app.txt_ProtFilePath.Value = 'D:\Work\Others\NeuropixelsPreliminary\StimSoftware\saving_metadata';
            app.BaseNameLabel = uilabel(app.ProtocolSavingFileInformationPanel);
            app.BaseNameLabel.Text = 'BaseName';
            app.txt_ProtFileBaseName = uieditfield(app.ProtocolSavingFileInformationPanel, 'text');
            app.txt_ProtFileBaseName.Value = 'CHANGE_ME';
            app.IndexLabel = uilabel(app.ProtocolSavingFileInformationPanel);
            app.IndexLabel.Text = 'Index';
            app.txt_ProtFileIndex = uieditfield(app.ProtocolSavingFileInformationPanel, 'text');
            app.txt_ProtFileIndex.Value = '1';
            app.but_ChangeSavePath = uibutton(app.ProtocolSavingFileInformationPanel, 'push');
            app.but_ChangeSavePath.ButtonPushedFcn = createCallbackFcn(app, @but_ChangeSavePathButtonPushed, true);
            app.but_ChangeSavePath.Text = '...';
            app.but_SaveStimMetadata = uibutton(app.ProtocolSavingFileInformationPanel, 'push');
            app.but_SaveStimMetadata.ButtonPushedFcn = createCallbackFcn(app, @but_SaveStimMetadataButtonPushed, true);
            app.but_SaveStimMetadata.Text = 'SaveStimMetadata';
            app.PlayRecParamsPanel = uipanel(app.UIFigure);
            app.PlayRecParamsPanel.Title = 'PlayRec Params';
            app.InputChEditFieldLabel = uilabel(app.PlayRecParamsPanel);
            app.InputChEditFieldLabel.Text = 'Input Ch.';
            app.txt_InputChs = uieditfield(app.PlayRecParamsPanel, 'text');
            app.txt_InputChs.Value = '-1';
            app.OutputChEditFieldLabel = uilabel(app.PlayRecParamsPanel);
            app.OutputChEditFieldLabel.Text = 'Output Ch.';
            app.txt_OutputChs = uieditfield(app.PlayRecParamsPanel, 'text');
            app.txt_OutputChs.Value = '5;6;7';
            app.SampPerFrameLabel = uilabel(app.PlayRecParamsPanel);
            app.SampPerFrameLabel.Text = 'SampPerFrame';
            app.txt_SamplesPerFrame = uieditfield(app.PlayRecParamsPanel, 'text');
            app.txt_SamplesPerFrame.Value = '1000';
            app.DeviceEditFieldLabel = uilabel(app.PlayRecParamsPanel);
            app.DeviceEditFieldLabel.Text = 'Device';
            app.txt_AudioDevice = uieditfield(app.PlayRecParamsPanel, 'text');
            app.txt_AudioDevice.Value = '2';
            app.but_PlaySounds = uibutton(app.PlayRecParamsPanel, 'push');
            app.but_PlaySounds.ButtonPushedFcn = createCallbackFcn(app, @but_PlaySoundsButtonPushed, true);
            app.but_PlaySounds.Text = 'PlayStimuli!!!!!';
            app.check_FreelyVocProtocol = uicheckbox(app.PlayRecParamsPanel);
            app.check_FreelyVocProtocol.Text = 'Freely Voc Protocol';
            app.SyncToneFreqHzLabel = uilabel(app.PlayRecParamsPanel);
            app.SyncToneFreqHzLabel.Text = 'SyncToneFreq (Hz)';
            app.txt_SyncToneFreq = uieditfield(app.PlayRecParamsPanel, 'text');
            app.txt_SyncToneFreq.Value = '2000';
            app.SyncToneDursLabel = uilabel(app.PlayRecParamsPanel);
            app.SyncToneDursLabel.Text = 'SyncToneDur (s)';
            app.txt_SyncToneDuration = uieditfield(app.PlayRecParamsPanel, 'text');
            app.txt_SyncToneDuration.Value = '5e-3';
            app.RecDurationminLabel = uilabel(app.PlayRecParamsPanel);
            app.RecDurationminLabel.Text = 'RecDuration (min)';
            app.txt_FreeVocRecDuration = uieditfield(app.PlayRecParamsPanel, 'text');
            app.txt_FreeVocRecDuration.Value = '10';
            app.WavFileInformationfreevocalizingbatsPanel = uipanel(app.UIFigure);
            app.WavFileInformationfreevocalizingbatsPanel.Title = 'Wav File Information - free vocalizing bats...';
            app.SavingPathEditFieldLabel_2 = uilabel(app.WavFileInformationfreevocalizingbatsPanel);
            app.SavingPathEditFieldLabel_2.Text = 'SavingPath';
            app.txt_RecWavFilePath = uieditfield(app.WavFileInformationfreevocalizingbatsPanel, 'text');
            app.txt_RecWavFilePath.Value = 'D:\Work\Others\NeuropixelsPreliminary\StimSoftware\wav_files';
            app.BaseNameLabel_2 = uilabel(app.WavFileInformationfreevocalizingbatsPanel);
            app.BaseNameLabel_2.Text = 'BaseName';
            app.txt_RecWavBaseName = uieditfield(app.WavFileInformationfreevocalizingbatsPanel, 'text');
            app.txt_RecWavBaseName.Value = 'CHANGE_ME_WAV';
            app.IndexLabel_2 = uilabel(app.WavFileInformationfreevocalizingbatsPanel);
            app.IndexLabel_2.Text = 'Index';
            app.txt_RecWavIndex = uieditfield(app.WavFileInformationfreevocalizingbatsPanel, 'text');
            app.txt_RecWavIndex.Value = '1';
            app.but_ChangeSaveRecWavPath = uibutton(app.WavFileInformationfreevocalizingbatsPanel, 'push');
            app.but_ChangeSaveRecWavPath.ButtonPushedFcn = createCallbackFcn(app, @but_ChangeSaveRecWavPathButtonPushed, true);
            app.but_ChangeSaveRecWavPath.Text = '...';
            app.ResponsevsPhaseparametersPanel = uipanel(app.UIFigure);
            app.ResponsevsPhaseparametersPanel.Title = 'Response vs. Phase parameters';
            app.TrialsperstimLabel = uilabel(app.ResponsevsPhaseparametersPanel);
            app.TrialsperstimLabel.Text = 'Trials per stim';
            app.txt_RvPStimTrials = uieditfield(app.ResponsevsPhaseparametersPanel, 'text');
            app.txt_RvPStimTrials.Value = '50';
            app.stimseparationsLabel = uilabel(app.ResponsevsPhaseparametersPanel);
            app.stimseparationsLabel.Text = 'stim separation (s)';
            app.txt_RvPStimSeparation = uieditfield(app.ResponsevsPhaseparametersPanel, 'text');
            app.txt_RvPStimSeparation.Value = '10';
            app.jittersEditFieldLabel = uilabel(app.ResponsevsPhaseparametersPanel);
            app.jittersEditFieldLabel.Text = 'jitter (s)';
            app.txt_RvPJitter = uieditfield(app.ResponsevsPhaseparametersPanel, 'text');
            app.txt_RvPJitter.Value = '3;6';
            app.lbl_RvPTotalLength = uilabel(app.ResponsevsPhaseparametersPanel);
            app.lbl_RvPTotalLength.Text = 'Total length = 0';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = StimSoftware

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end